classdef TTF_estimation_KneeExtension < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TTFEstimatorKneeextensionLabel  matlab.ui.control.Label
        ExperimentaltaskparametersLabel  matlab.ui.control.Label
        minallouttestparametersLabel    matlab.ui.control.Label
        TimetotaskfailureLabel          matlab.ui.control.Label
        RestsEditField                  matlab.ui.control.NumericEditField
        RestsEditFieldLabel             matlab.ui.control.Label
        ContractionsEditField           matlab.ui.control.NumericEditField
        ContractionsEditFieldLabel      matlab.ui.control.Label
        ContractionTimeLabel            matlab.ui.control.Label
        EstimateTTFButton               matlab.ui.control.Button
        WorkloadETEditField             matlab.ui.control.NumericEditField
        WorkloadETEditFieldLabel        matlab.ui.control.Label
        ETNmEditField                   matlab.ui.control.NumericEditField
        ETNmEditFieldLabel              matlab.ui.control.Label
        TIaboveETNmsEditField           matlab.ui.control.NumericEditField
        TIaboveETNmsEditFieldLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TIaboveET
        ET
        Workload
        Workload_relative
        Contract_T
        TI
        Contraction_time
        Contraction
        Rest
        TI_estimated
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TIaboveETNmsEditField
        function TIaboveETNmsEditFieldValueChanged(app, event)
            app.TIaboveET = app.TIaboveETNmsEditField.Value;
            
        end

        % Value changed function: ETNmEditField
        function ETNmEditFieldValueChanged(app, event)
            app.ET = app.ETNmEditField.Value;
            
        end

        % Value changed function: WorkloadETEditField
        function WorkloadETEditFieldValueChanged(app, event)
            app.Workload_relative = app.WorkloadETEditField.Value;
            app.Workload = app.Workload_relative/100*app.ET;
        end

        % Button pushed function: EstimateTTFButton
        function EstimateTTFButtonPushed(app, event)
          % Contraction time estimation
            app.Contraction_time = app.TIaboveET/(app.ET*(app.Workload_relative/100-1));
            app.ContractionTimeLabel.Text = ['Contraction Time = ' num2str(round(app.Contraction_time, 1)) ' s'];
            
            DutyCycle_time = app.Contraction + app.Rest;
            TTF = (app.Contraction_time)/app.Contraction * DutyCycle_time;
            TTF_min = round(TTF/60, 1);
            app.TimetotaskfailureLabel.Text = ['TTF = ' num2str(TTF_min) ' min'];
            
            app.TI_estimated = app.TIaboveET + app.ET*app.Contraction_time;
            
            
            % Plot TI vs. ContractT graph
            x = 1:(app.Contraction_time*2); % 2 is an arbitrary number
            y = app.TIaboveET + (app.ET*x);
            plot(app.UIAxes, x, y);
            txt = ['y = ' num2str(app.TIaboveET) '+' num2str(app.ET) 'x'];
            text(app.UIAxes, 30, (app.TI_estimated*1.8), txt); % 1.8 is just an arbitrary number for the figure size

            xl = [app.Contraction_time app.Contraction_time];
            yl = [0 (app.TI_estimated)];
            
            xr = [0 app.Contraction_time];
            yr = [(app.TI_estimated) (app.TI_estimated)];
            line(app.UIAxes, xl, yl, 'color', 'red', 'linestyle', '--');
            line(app.UIAxes, xr, yr, 'color', 'red', 'linestyle', '--');
            text(app.UIAxes, (app.Contraction_time+20), (app.TIaboveET + app.ET*app.Contraction_time), 'Estimated contraction time/T-I', "Color", 'r')
        end

        % Value changed function: ContractionsEditField
        function ContractionsEditFieldValueChanged(app, event)
            app.Contraction = app.ContractionsEditField.Value;
        end

        % Value changed function: RestsEditField
        function RestsEditFieldValueChanged(app, event)
            app.Rest = app.RestsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Contraction time (s)')
            ylabel(app.UIAxes, 'Torque-Impulse (Nm.s)')
            app.TIaboveETNmsEditFieldLabel = uilabel(app.UIFigure);
            app.TIaboveETNmsEditFieldLabel.Text = 'TI above ET (Nm.s)';
            app.TIaboveETNmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TIaboveETNmsEditField.ValueChangedFcn = createCallbackFcn(app, @TIaboveETNmsEditFieldValueChanged, true);
            app.ETNmEditFieldLabel = uilabel(app.UIFigure);
            app.ETNmEditFieldLabel.Text = 'ET (Nm)';
            app.ETNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ETNmEditField.ValueChangedFcn = createCallbackFcn(app, @ETNmEditFieldValueChanged, true);
            app.WorkloadETEditFieldLabel = uilabel(app.UIFigure);
            app.WorkloadETEditFieldLabel.Text = 'Workload (%ET)';
            app.WorkloadETEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorkloadETEditField.ValueChangedFcn = createCallbackFcn(app, @WorkloadETEditFieldValueChanged, true);
            app.EstimateTTFButton = uibutton(app.UIFigure, 'push');
            app.EstimateTTFButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateTTFButtonPushed, true);
            app.EstimateTTFButton.Text = 'Estimate TTF';
            app.ContractionTimeLabel = uilabel(app.UIFigure);
            app.ContractionTimeLabel.Text = 'Contraction Time';
            app.ContractionsEditFieldLabel = uilabel(app.UIFigure);
            app.ContractionsEditFieldLabel.Text = 'Contraction (s)';
            app.ContractionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContractionsEditField.ValueChangedFcn = createCallbackFcn(app, @ContractionsEditFieldValueChanged, true);
            app.RestsEditFieldLabel = uilabel(app.UIFigure);
            app.RestsEditFieldLabel.Text = 'Rest (s)';
            app.RestsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RestsEditField.ValueChangedFcn = createCallbackFcn(app, @RestsEditFieldValueChanged, true);
            app.TimetotaskfailureLabel = uilabel(app.UIFigure);
            app.TimetotaskfailureLabel.Text = 'Time to task failure';
            app.minallouttestparametersLabel = uilabel(app.UIFigure);
            app.minallouttestparametersLabel.Text = '5-min all-out test parameters';
            app.ExperimentaltaskparametersLabel = uilabel(app.UIFigure);
            app.ExperimentaltaskparametersLabel.Text = 'Experimental task parameters';
            app.TTFEstimatorKneeextensionLabel = uilabel(app.UIFigure);
            app.TTFEstimatorKneeextensionLabel.Text = 'TTF Estimator (Knee extension)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTF_estimation_KneeExtension

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end