classdef TTF_estimation_cycling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TTFEstimatorCyclingLabel      matlab.ui.control.Label
        ExperimentaltaskparametersLabel  matlab.ui.control.Label
        minallouttestparametersLabel  matlab.ui.control.Label
        TimetotaskfailureLabel        matlab.ui.control.Label
        EstimateTTFButton             matlab.ui.control.Button
        WorkloadEPEditField           matlab.ui.control.NumericEditField
        WorkloadEPEditFieldLabel      matlab.ui.control.Label
        EPEditField                   matlab.ui.control.NumericEditField
        EPWEditFieldLabel             matlab.ui.control.Label
        WaboveEPkJEditField           matlab.ui.control.NumericEditField
        WaboveEPkJEditFieldLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        WaboveEP_kJ
        WaboveEP_J
        EP
        Workload
        Workload_relative
        W
        Cycling_time
        Cycling_time_min
        Contraction
        W_estimated
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WaboveEPkJEditField
        function WaboveEPkJEditFieldValueChanged(app, event)
            app.WaboveEP_kJ= app.WaboveEPkJEditField.Value; % in kJ
            app.WaboveEP_J = app.WaboveEP_kJ* 1000; % in J
            
        end

        % Value changed function: EPEditField
        function EPEditFieldValueChanged(app, event)
            app.EP = app.EPEditField.Value;
            
        end

        % Value changed function: WorkloadEPEditField
        function WorkloadEPEditFieldValueChanged(app, event)
            app.Workload_relative = app.WorkloadEPEditField.Value; % %EP
            app.Workload = app.Workload_relative/100*app.EP;
        end

        % Button pushed function: EstimateTTFButton
        function EstimateTTFButtonPushed(app, event)
          % Contraction time estimation
            app.Cycling_time = app.WaboveEP_J/(app.EP*(app.Workload_relative/100-1)); % in s
            app.Cycling_time_min = app.Cycling_time/60; % cycling time in min
            
            app.W_estimated = app.WaboveEP_kJ+ (app.EP/1000*app.Cycling_time); % Estimated total work (kJ) during experimental task

            % Display the estimated TTF
            app.TimetotaskfailureLabel.Text = ['TTF = ' num2str(round(app.Cycling_time_min,2)) ' min']; % in min  
            
            
            % Plot W vs. Cycling time graph (Need to fix this according to
            % the J vs kJ conversion!!!!
            x = 1:(app.Cycling_time*2); % 2 is an arbitrary number
            y = app.WaboveEP_kJ+ (app.EP/1000*x); % Divide by 1000 to obtain kJ
            plot(app.UIAxes, x, y); % converting J to kJ
            
            % Put the linear equation on the plot
            txt = ['y = ' num2str(app.WaboveEP_kJ) '+' num2str(app.EP/1000) 'x']; 
            text(app.UIAxes, 30, (app.W_estimated*1.8), txt); % 1.8 is just an arbitrary number for the figure size

            xl = [app.Cycling_time app.Cycling_time];
            yl = [0 (app.W_estimated)];
            
            xr = [0 app.Cycling_time];
            yr = [(app.W_estimated) (app.W_estimated)];
            line(app.UIAxes, xl, yl, 'color', 'red', 'linestyle', '--');
            line(app.UIAxes, xr, yr, 'color', 'red', 'linestyle', '--');
            text(app.UIAxes, (app.Cycling_time+20), (app.WaboveEP_kJ + app.EP/1000*app.Cycling_time), 'Estimated cycling time/W', "Color", 'r')
        end

        % Callback function
        function ContractionsEditFieldValueChanged(app, event)
            app.Contraction = app.ContractionsEditField.Value;
        end

        % Callback function
        function RestsEditFieldValueChanged(app, event)
            app.Rest = app.RestsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Cycling time (s)')
            ylabel(app.UIAxes, 'Work (kJ)')
            app.WaboveEPkJEditFieldLabel = uilabel(app.UIFigure);
            app.WaboveEPkJEditFieldLabel.Text = 'W above EP (kJ)';
            app.WaboveEPkJEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaboveEPkJEditField.ValueChangedFcn = createCallbackFcn(app, @WaboveEPkJEditFieldValueChanged, true);
            app.EPWEditFieldLabel = uilabel(app.UIFigure);
            app.EPWEditFieldLabel.Text = 'EP (W)';
            app.EPEditField = uieditfield(app.UIFigure, 'numeric');
            app.EPEditField.ValueChangedFcn = createCallbackFcn(app, @EPEditFieldValueChanged, true);
            app.WorkloadEPEditFieldLabel = uilabel(app.UIFigure);
            app.WorkloadEPEditFieldLabel.Text = 'Workload (%EP)';
            app.WorkloadEPEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorkloadEPEditField.ValueChangedFcn = createCallbackFcn(app, @WorkloadEPEditFieldValueChanged, true);
            app.EstimateTTFButton = uibutton(app.UIFigure, 'push');
            app.EstimateTTFButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateTTFButtonPushed, true);
            app.EstimateTTFButton.Text = 'Estimate TTF';
            app.TimetotaskfailureLabel = uilabel(app.UIFigure);
            app.TimetotaskfailureLabel.Text = 'Time to task failure';
            app.minallouttestparametersLabel = uilabel(app.UIFigure);
            app.minallouttestparametersLabel.Text = '3-min all-out test parameters';
            app.ExperimentaltaskparametersLabel = uilabel(app.UIFigure);
            app.ExperimentaltaskparametersLabel.Text = 'Experimental task parameters';
            app.TTFEstimatorCyclingLabel = uilabel(app.UIFigure);
            app.TTFEstimatorCyclingLabel.Text = 'TTF Estimator (Cycling)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTF_estimation_cycling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end